classdef FileCompressionProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageCompressionDecompressionUIFigure  matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        CompressionMenu                matlab.ui.container.Menu
        DecompressionMenu              matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        VideoMenu                      matlab.ui.container.Menu
        CompressionMenu_2              matlab.ui.container.Menu
        DecompressionMenu_2            matlab.ui.container.Menu
        ExitMenu_2                     matlab.ui.container.Menu
        SelectFileButton               matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        CompressionRatioLabel          matlab.ui.control.Label
        CompressionRatioEditField      matlab.ui.control.EditField
        LblSelectFile                  matlab.ui.control.Label
        CompressFileButton             matlab.ui.control.Button
        DecompressFileButton           matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        CompressionSizeEditFieldLabel  matlab.ui.control.Label
        CompressionSizeEditField       matlab.ui.control.EditField
        OriginalSizeEditFieldLabel     matlab.ui.control.Label
        OriginalSizeEditField          matlab.ui.control.EditField
        ClearAllButton                 matlab.ui.control.Button
        MatrixSizeDropDownLabel        matlab.ui.control.Label
        MatrixSizeDropDown             matlab.ui.control.DropDown
        CoefficientValueDropDownLabel  matlab.ui.control.Label
        CoefficientValueDropDown       matlab.ui.control.DropDown
        FilenameLabel                  matlab.ui.control.Label
        UnCompressedSizeLabel          matlab.ui.control.Label
        UnCompressedSizeEditField      matlab.ui.control.EditField
    end
    
    methods (Access = private)
    
        % Menu selected function: CompressionMenu
        function CompressionMenuSelected(app, event)
            app.LblSelectFile.Visible=true;
            app.LblSelectFile.Enable=true;
            app.SelectFileButton.Enable=true;
            %print('Enter file for compression');
        end
        
        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            global fname;
            UIAxes.Title.string='Input Image';
            UIAxes2.Title.string='Output Image';
            [filename pathname]=uigetfile({'*.png'},'File Selector');
            fname=fullfile(pathname,filename);
            imshow(fname,'Parent',app.UIAxes);
            msgbox ('done1');    
            app.DecompressFileButton.Enable = true;
            app.CompressFileButton.Enable=true;
            app.LblSelectFile.Text=fname;
            app.LblSelectFile.Visible=false;
            app.FilenameLabel.Text=filename;
            app.FilenameLabel.Visible=false;
            
        end

        % Callback function: CompressFileButton, 
        % CompressionSizeEditField
        function CompressFileButtonPushed(app, event)
            app.LblSelectFile.Visible=false;
            app.CompressionRatioEditField.Enable=true;
            UnCompressedSizeEditField.Visble=false;
            fname=app.LblSelectFile.Text;
            filename = app.FilenameLabel.Text;
            msgbox('done');
            mat_size=app.MatrixSizeDropDown.Value
            coeff_val=app.CoefficientValueDropDown.Value
            i_blocksize = str2double(mat_size);
            i_coeff = str2double(coeff_val);
            msgbox('done2');
            im = imread(fname);
            outpath = strcat('C:\Users\nirup\Desktop\Shalini Study Reading University\Image processing\2018_ImageCompression\Demo\Compressed_',string(i_coeff),'_',filename);
            T = dctmtx(i_blocksize);
        
        end

         % Button pushed function: CompressFileButton
        function CompressFileButtonPushed(app, event)
            app.LblSelectFile.Visible=false;
            fname=app.LblSelectFile.Text;
            msgbox('done');
            
        %imageinfo;  % calculate the information of Image (eg. size in bytes)
         %wcompress('c',I,compression,ezw)
        red = double(I(:,:,1));
       green = double(I(:,:,2));
        blue = double(I(:,:,3));
             [u,s,v] = svds(red);
        imred = uint8(u * s * transpose(v));
        im(:,:,1) = imred;
       im(:,:,2) = imgreen;
       im(:,:,3) = imblue;
              
       figure,
      % subplot(3,3,1);
       imshow(I);        % show image in figure form
       title('Input Image')
       imageinfo;
       
      figure,
         subplot(3,2,1);
          imshow(red);
          title('red component')
          subplot(3,2,2);
           imshow(green);
           title('green component')
           subplot(3,2,3);
            imshow(blue);
            title('blue component')
       [u,s,v] = svds(green);
        imgreen = uint8(u * s * transpose(v));
        
        [u,s,v] = svds(blue);
       imblue = uint8(u * s * transpose(v));
       
          
            subplot(3,2,4);
             imshow(imred);
             title('red svd')
             subplot(3,2,5);
              imshow(imgreen);
              title('green svd')
              subplot(3,2,6);
               imshow(imblue);
               title('blue svd')
               
       figure,        
      %subplot(3,3,8)
      imshow(im);
     title('output Image')
     imageinfo;
     imsave


        end
    end
    
    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageCompressionDecompressionUIFigure = uifigure;
            app.ImageCompressionDecompressionUIFigure.Name = 'Image Compression/Decompression';
            app.FileMenu = uimenu(app.ImageCompressionDecompressionUIFigure);
            app.FileMenu.Text = 'File';
            app.CompressionMenu = uimenu(app.FileMenu);
            app.CompressionMenu.MenuSelectedFcn = createCallbackFcn(app, @CompressionMenuSelected, true);
            app.CompressionMenu.Tooltip = {'Open file for compression'};
            app.CompressionMenu.Text = 'Compression';
            app.DecompressionMenu = uimenu(app.FileMenu);
            app.DecompressionMenu.Text = 'Decompression';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Text = 'Exit';
            app.VideoMenu = uimenu(app.ImageCompressionDecompressionUIFigure);
            app.VideoMenu.Text = 'Video';
            app.CompressionMenu_2 = uimenu(app.VideoMenu);
            app.CompressionMenu_2.Text = 'Compression';
            app.DecompressionMenu_2 = uimenu(app.VideoMenu);
            app.DecompressionMenu_2.Text = 'Decompression';
            app.ExitMenu_2 = uimenu(app.VideoMenu);
            app.ExitMenu_2.Text = 'Exit';
            app.SelectFileButton = uibutton(app.ImageCompressionDecompressionUIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Enable = 'off';
            app.SelectFileButton.Text = 'Select File';
            app.UIAxes = uiaxes(app.ImageCompressionDecompressionUIFigure);
            title(app.UIAxes, 'Input Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CompressionRatioEditFieldLabel = uilabel(app.ImageCompressionDecompressionUIFigure);
            app.CompressionRatioEditFieldLabel.Text = 'Compression Ratio';
            app.CompressionRatioEditField = uieditfield(app.ImageCompressionDecompressionUIFigure, 'text');
            app.LblSelectFile = uilabel(app.ImageCompressionDecompressionUIFigure);
            app.LblSelectFile.Enable = 'off';
            app.LblSelectFile.Visible = 'off';
            app.LblSelectFile.Text = 'Select file';
            app.CompressFileButton = uibutton(app.ImageCompressionDecompressionUIFigure, 'push');
            app.CompressFileButton.ButtonPushedFcn = createCallbackFcn(app, @CompressFileButtonPushed, true);
            app.CompressFileButton.Enable = 'off';
            app.CompressFileButton.Tooltip = {'Click to compress file'};
            app.CompressFileButton.Text = 'Compress File';
            app.DecompressFileButton = uibutton(app.ImageCompressionDecompressionUIFigure, 'push');
            app.DecompressFileButton.Enable = 'off';
            app.DecompressFileButton.Tooltip = {'Click to decompress file'};
            app.DecompressFileButton.Text = 'Decompress File';
            app.UIAxes2 = uiaxes(app.ImageCompressionDecompressionUIFigure);
            title(app.UIAxes2, 'Output  Image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.CompressionSizeEditFieldLabel = uilabel(app.ImageCompressionDecompressionUIFigure);
            app.CompressionSizeEditFieldLabel.Text = 'Compression Size';
            app.CompressionSizeEditField = uieditfield(app.ImageCompressionDecompressionUIFigure, 'text');
            app.ClearAllButton = uibutton(app.ImageCompressionDecompressionUIFigure, 'state');
            app.ClearAllButton.Text = {'Clear All'; ''};
            app.OriginalSizeEditFieldLabel = uilabel(app.ImageCompressionDecompressionUIFigure);
            app.OriginalSizeEditFieldLabel.Text = 'Original Size';
            app.OriginalSizeEditField = uieditfield(app.ImageCompressionDecompressionUIFigure, 'text');
        end
        
    end

methods (Access = public)

        % Construct app
        function app = FileCompressionProject

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageCompressionDecompressionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageCompressionDecompressionUIFigure)
        end
    end
end